classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StyletransferProjektAnalizaObrazwUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        ContentImage            matlab.ui.control.Image
        CenterPanel             matlab.ui.container.Panel
        StyleExampleImage       matlab.ui.control.Image
        PrzeniestylButton       matlab.ui.control.Button
        WybierzobrazButton      matlab.ui.control.Button
        WybierzstylButtonGroup  matlab.ui.container.ButtonGroup
        PollockButton           matlab.ui.control.ToggleButton
        KossakButton            matlab.ui.control.ToggleButton
        KlimtButton             matlab.ui.control.ToggleButton
        JasiskiButton           matlab.ui.control.ToggleButton
        DurerButton             matlab.ui.control.ToggleButton
        RightPanel              matlab.ui.container.Panel
        TransferImage           matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Paths to style images displayed above controls.
    properties (Access = private)
        durerImPath
        dur
        jasinskiImPath
        jas
        klimtImPath
        kli
        kossakImPath
        kos
        pollockImPath
        pol
        imagePaths
        models
    end
    
    methods (Access = private)
        
        function initializeStylePaths(app)
            "Trwa ładowanie modeli..."
            o(1) = dlnetwork([convolution2dLayer(5,20),batchNormalizationLayer,reluLayer,fullyConnectedLayer(10),softmaxLayer],'Initialize',false);
            app.models = [o];
            [app.models(1), app.models(2), app.models(3), app.models(4), app.models(5)] = loadModels();

            app.durerImPath = "model/durer.jpg";
            app.DurerButton.UserData = 1;

            app.jasinskiImPath = "model/jasinski.jpg";
            app.JasiskiButton.UserData = 2;

            app.klimtImPath = "model/klimt.jpg";
            app.KlimtButton.UserData = 3;

            app.kossakImPath = "model/kossak.jpg";
            app.KossakButton.UserData = 4;

            app.pollockImPath = "model/pollock.jpg";
            app.PollockButton.UserData = 5;

            app.imagePaths = [app.durerImPath, app.jasinskiImPath, app.klimtImPath, app.kossakImPath, app.pollockImPath];
        end
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeStylePaths(app);
        end

        % Button pushed function: WybierzobrazButton
        function WybierzobrazButtonPushed(app, event)
            [file] = uigetfile({'*.png';'*.jpg';'*.jpeg';'*.gif'});
            app.ContentImage.ImageSource = file;
        end

        % Selection changed function: WybierzstylButtonGroup
        function WybierzstylButtonGroupSelectionChanged(app, event)
            selectedButton = app.WybierzstylButtonGroup.SelectedObject;
            selectedButton.UserData
            app.imagePaths(selectedButton.UserData)
            app.StyleExampleImage.ImageSource = app.imagePaths(selectedButton.UserData);
        end

        % Button pushed function: PrzeniestylButton
        function PrzeniestylButtonPushed(app, event)
            % Run magic style transfer function by MM
            'Przenieś styl event'
            app.models
            app.WybierzstylButtonGroup.SelectedObject.UserData
            app.TransferImage.ImageSource = styleTransfer(app.ContentImage.ImageSource,app.models(app.WybierzstylButtonGroup.SelectedObject.UserData));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.StyletransferProjektAnalizaObrazwUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {492, 492, 492};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {492, 492};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {424, '1x', 426};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StyletransferProjektAnalizaObrazwUIFigure = uifigure('Visible', 'off');
            app.StyletransferProjektAnalizaObrazwUIFigure.Name = 'Style transfer - Projekt Analiza Obrazów';
            app.StyletransferProjektAnalizaObrazwUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.StyletransferProjektAnalizaObrazwUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ContentImage = uiimage(app.LeftPanel);
            app.ContentImage.ImageSource = 'test.jpg';
            app.CenterPanel = uipanel(app.GridLayout);
            app.WybierzstylButtonGroup = uibuttongroup(app.CenterPanel);
            app.WybierzstylButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WybierzstylButtonGroupSelectionChanged, true);
            app.WybierzstylButtonGroup.Title = 'Wybierz styl';
            app.DurerButton = uitogglebutton(app.WybierzstylButtonGroup);
            app.DurerButton.Text = 'Durer';
            app.DurerButton.Value = true;
            app.JasiskiButton = uitogglebutton(app.WybierzstylButtonGroup);
            app.JasiskiButton.Text = 'Jasiński';
            app.KlimtButton = uitogglebutton(app.WybierzstylButtonGroup);
            app.KlimtButton.Text = 'Klimt';
            app.KossakButton = uitogglebutton(app.WybierzstylButtonGroup);
            app.KossakButton.Text = 'Kossak';
            app.PollockButton = uitogglebutton(app.WybierzstylButtonGroup);
            app.PollockButton.Text = 'Pollock';
            app.WybierzobrazButton = uibutton(app.CenterPanel, 'push');
            app.WybierzobrazButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzobrazButtonPushed, true);
            app.WybierzobrazButton.Text = 'Wybierz obraz';
            app.PrzeniestylButton = uibutton(app.CenterPanel, 'push');
            app.PrzeniestylButton.ButtonPushedFcn = createCallbackFcn(app, @PrzeniestylButtonPushed, true);
            app.PrzeniestylButton.Text = 'Przenieś styl';
            app.StyleExampleImage = uiimage(app.CenterPanel);
            app.StyleExampleImage.ImageSource = 'durer.jpg';
            app.RightPanel = uipanel(app.GridLayout);
            app.TransferImage = uiimage(app.RightPanel);
            app.TransferImage.ImageSource = 'download.png';
            % Show the figure after all components are created
            app.StyletransferProjektAnalizaObrazwUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StyletransferProjektAnalizaObrazwUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StyletransferProjektAnalizaObrazwUIFigure)
        end
    end
end